classdef MISHA_capturing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        focus         matlab.ui.control.Button
        capture_raw   matlab.ui.control.Button
        capture_flat  matlab.ui.control.Button
        test_leds     matlab.ui.control.Button
        set_exposure  matlab.ui.control.Button
        cancel        matlab.ui.control.Button
        RightPanel    matlab.ui.container.Panel
        TextArea      matlab.ui.control.TextArea
        zoom_2        matlab.ui.control.UIAxes
        UIAxes        matlab.ui.control.UIAxes
        zoom          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
       
        
    end
    
    properties (Access = public)
        capture_info = struct();  % structure to hold info for capture settings
        cancel_capture = false;  % stop image capture
        
        % wavelength names
        wavelengths = ['365 nm', '385 nm', '395 nm', '420 nm', ...
                           '450 nm', '470 nm', '490 nm', '520 nm', ...
                           '560 nm', '590 nm', '615 nm', '630 nm', ...
                           '660 nm', '730 nm', '850 nm', '940 nm']   
        
        % wavelengths
        waves = [365,385,395,420, ...
                   450,470,490,520, ...
                   560,590,615,630, ...
                   660,730,850,940]           

        % exposure time   
        % MISHA 2 (Andrew)
        %exposure_time_to_set = [0.07,0.09,0.09,0.09 ...
        %                                 ,0.03,0.045,0.06,0.09 ...
        %                                 ,0.05,0.42,0.075,0.08 ...
        %                                 ,0.08,0.3,0.2,0.6]*1.5;
        % MISHA 1 (Tania)
        exposure_time_to_set = [0.09,0.09,0.09,0.09 ...
                                         ,0.03,0.045,0.06,0.09 ...
                                         ,0.05,0.42,0.075,0.08 ...
                                         ,0.08,0.3,0.2,0.6]*0.7;
        
        % MISHA 3 (Helen)
%         exposure_time_to_set = [0.09,0.09,0.09,0.09 ...
%                                          ,0.03,0.045,0.06,0.07 ...
%                                          ,0.05,0.35,0.075,0.08 ...
%                                          ,0.06,0.1,0.2,0.6]*1.5;
%         
        
        
        exposure_gain = 1;   % exposure percentage to use - distance about 21"
        exposure_gain2 = 0.66;
        exposure_gain3 = 1.5; 
        exposure_gain4 = 2;
        
        counter_for_restart = 1;
        max_counter = 6;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                ser = serialport('COM3',9600);
                write(ser,"0,0\n","string");
                clear ser
            catch
                clear ser
                app.TextArea.Value = 'LEDs not connected';
            end
            app.zoom.XColor = [0.94,0.94,0.94];
            app.UIFigure.Icon = 'splash.PNG';
            app.UIFigure.Name = 'MISHA image capture';
            set(app.cancel,'enable','off');
            set(app.TextArea,'visible','off');
            
        end

        % Button pushed function: focus
        function focusButtonPushed(app, event)
            
            try
                ser = serialport('COM3',9600);
                write(ser,"0,0\n","string")
                clear ser
            catch 
                write(ser,"0,0\n","string")
                clear ser
            end
            
            try
                set(app.capture_flat,'enable','off');
                set(app.capture_raw,'enable','off');
                set(app.test_leds,'enable','off');
                set(app.set_exposure,'enable','off');
                set(app.cancel,'enable','on');
                set(app.TextArea,'visible','on');
                
                app.cancel_capture = false;
                
                app.TextArea.Value = 'Connecting to camera';
                cla(app.UIAxes) 
                    
                if app.cancel_capture
                    app.cancel_capture = false;
                    app.TextArea.Value = 'Cancelling... this will take a few seconds';
                    write(ser,"0,0\n","string")
                    pause(3)
                    set(app.capture_flat,'enable','on');
                    set(app.capture_raw,'enable','on');
                    set(app.test_leds,'enable','on');
                    set(app.set_exposure,'enable','on');
                    set(app.focus,'enable','on');
                    set(app.cancel,'enable','off');
                    % switch off all LED's
                    
                    app.TextArea.Value = 'Operation Cancelled';
                    return
                end
                
                % connecting to default camera
                vid=videoinput('pixelinkimaq', 1, 'MONO16_5472x3648')
                vid.FramesPerTrigger = 1;
                src = getselectedsource(vid);
                set(src,'ExposureMode','manual');
                set(src,'Exposure',0.05)
              
                
                try    
                    % switch on LED to see
                    clear ser
                    pause(2)
                    ser = serialport('COM3',9600);
                    
                    pause(2)
                    send_on = ["615,100"];
                    %switch LED on
                    write(ser,send_on(1),"string");
                    app.TextArea.Value = 'Switching LED on';
                catch
                    app.TextArea.Value = 'LED not found';
                end
                        
                cnt = 1;   
                
                while ~app.cancel_capture
                    try
                        scroll(app.TextArea, 'bottom')
                        if app.cancel_capture
                            app.cancel_capture = false;
                            app.TextArea.Value = 'Cancelling... this will take a few seconds';
                            % switch off all LED's
                            write(ser,"0,0\n","string")
                            pause(3)
                            set(app.capture_flat,'enable','on');
                            set(app.capture_raw,'enable','on');
                            set(app.test_leds,'enable','on');
                            set(app.set_exposure,'enable','on');
                            set(app.focus,'enable','on');
                            set(app.cancel,'enable','off');
                            
                            app.TextArea.Value = 'Operation Cancelled';
                            return
                        end
                        
                        frame=getsnapshot(vid);
                        img = (double(frame)-min(double(frame(:))))/(double(max(frame(:)))-min(double(frame(:))));
                        %imshow(img,'parent',app.UIAxes); 
                        title(app.UIAxes, []);
                        xlabel(app.UIAxes, []);
                        ylabel(app.UIAxes, []);
                        app.UIAxes.XAxis.TickLabels = {};
                        app.UIAxes.YAxis.TickLabels = {};
                        % Display image and stretch to fill axes
                        I = imshow(img, 'Parent', app.UIAxes, ...
                            'XData', [1 app.UIAxes.Position(3)], ...
                            'YData', [1 app.UIAxes.Position(4)]);
                        % Set limits of axes
                        app.UIAxes.XLim = [0 I.XData(2)];
                        app.UIAxes.YLim = [0 I.YData(2)];
                        app.UIAxes.Toolbar
                        drawnow;
                        
                        row_size = 500;
                        col_size = 500;
                        zoom_start_row = uint16(size(img,1)/2)-row_size;
                        zoom_start_col = uint16(size(img,2)/2)-col_size;
                        
                        img_zoom = img(zoom_start_row: zoom_start_row + row_size*2, zoom_start_col: zoom_start_col+col_size*2);
                        
                        %imshow(img_zoom,'parent',app.zoom);
                        
                        % Remove title, axis labels, and tick labels
                        title(app.zoom, []);
                        xlabel(app.zoom, []);
                        ylabel(app.zoom, []);
                        app.zoom.XAxis.TickLabels = {};
                        app.zoom.YAxis.TickLabels = {};
                        % Display image and stretch to fill axes
                        I = imshow(img_zoom, 'Parent', app.zoom, ...
                            'XData', [1 app.zoom.Position(3)], ...
                            'YData', [1 app.zoom.Position(4)]);
                        % Set limits of axes
                        app.zoom.XLim = [0 I.XData(2)];
                        app.zoom.YLim = [0 I.YData(2)];
                        
                        drawnow;
                                                
                        row_size = 100;
                        col_size = 100;
                        zoom_start_row = uint16(size(img_zoom,1)/2)-row_size;
                        zoom_start_col = uint16(size(img_zoom,2)/2)-col_size;
                        
                        img_zoom_2 = img_zoom(zoom_start_row: zoom_start_row + row_size*2, zoom_start_col: zoom_start_col+col_size*2);
                        %imshow(img_zoom_2,'parent',app.zoom_2);
                        title(app.zoom_2, []);
                        xlabel(app.zoom_2, []);
                        ylabel(app.zoom_2, []);
                        app.zoom_2.XAxis.TickLabels = {};
                        app.zoom_2.YAxis.TickLabels = {};
                        % Display image and stretch to fill axes
                        I = imshow(img_zoom_2, 'Parent', app.zoom_2, ...
                            'XData', [1 app.zoom_2.Position(3)], ...
                            'YData', [1 app.zoom_2.Position(4)]);
                        % Set limits of axes
                        app.zoom_2.XLim = [0 I.XData(2)];
                        app.zoom_2.YLim = [0 I.YData(2)];
                        drawnow;
                        
                        [FX,FY] = gradient(img_zoom*255);
                        mx = max(max(FX(:)),max(FY(:)));
                        app.TextArea.Value{cnt} = ['Sharpness: ', num2str(round(mx))];
                        scroll(app.TextArea, 'bottom')
                        cnt = cnt + 1;
                        pause(0.4) 
                    catch
                       ; 
                    end
                end
                
                try
                    %switch LED off
                    write(ser,"0,0\n","string");
                    clear ser
                    app.cancel_capture = false;
                catch
                    ;
                end
                set(app.cancel,'enable','off');
                set(app.capture_flat,'enable','on');
                set(app.capture_raw,'enable','on');
                set(app.test_leds,'enable','on');
                set(app.set_exposure,'enable','on');
                app.zoom.XColor = [0.94,0.94,0.94];
                cla(app.zoom) 
                cla(app.zoom_2)
                app.cancel_capture = false;
            catch
                set(app.cancel,'enable','off');
                set(app.capture_flat,'enable','on');
                set(app.capture_raw,'enable','on');
                set(app.test_leds,'enable','on');
                set(app.set_exposure,'enable','on');
                app.zoom.XColor = [0.94,0.94,0.94];
                cla(app.zoom) 
                cla(app.zoom_2)
                app.cancel_capture = false;
                try
                    write(ser,"0,0\n","string");
                    clear ser
                catch
                    app.TextArea.Value = 'LEDs not connected';
                end
                app.cancel_capture = false;
            end
            
        if app.counter_for_restart == app.max_counter
            delete(app.UIFigure); %close the app
            MISHA_capturing; %re-open it again
        else
            app.counter_for_restart = app.counter_for_restart + 1;
        end
                
            
        end

        % Button pushed function: capture_raw
        function capture_rawButtonPushed(app, event)

            try
                ser = serialport('COM3',9600);
                write(ser,"0,0\n","string")
                clear ser
            catch 
                write(ser,"0,0\n","string")
                clear ser
            end
            
            set(app.capture_flat,'enable','off');
            set(app.capture_raw,'enable','off');
            set(app.test_leds,'enable','off');
            set(app.set_exposure,'enable','off');
            set(app.focus,'enable','off');
            set(app.TextArea,'enable','on');
            set(app.cancel,'enable','on');
            set(app.TextArea,'visible','on');
            cla(app.UIAxes) 
            
            try
            
                % get filepath to save captured images
                user_name= getenv('username');
                filepath = ['C:\Users\',user_name,'\Desktop\'];
                app.capture_info.filepath = uigetdir(filepath);
                % find last folder to asign object name
                pathparts = strsplit(app.capture_info.filepath,filesep);
                app.capture_info.object_name = pathparts{end};
                app.capture_info.fname = 'raw';
                capture_images(app);
                
            catch
                ;
            end

            app.zoom.XColor = [0.94,0.94,0.94];
            cla(app.zoom) 
            cla(app.zoom_2)
            set(app.capture_flat,'enable','on');
            set(app.capture_raw,'enable','on');
            set(app.test_leds,'enable','on');
            set(app.set_exposure,'enable','on');
            set(app.focus,'enable','on');
            set(app.cancel,'enable','off');
            app.cancel_capture = false;
           
            
        if app.counter_for_restart == app.max_counter
            delete(app.UIFigure); %close the app
            MISHA_capturing; %re-open it again
        else
            app.counter_for_restart = app.counter_for_restart + 1;
        end    
            
            
        end

        % Button pushed function: cancel
        function cancelButtonPushed(app, event)
            app.cancel_capture = true;
            imaqreset();
            try
                ser = serialport('COM3',9600);
                write(ser,"0,0\n","string");
                clear ser
            catch
                clear ser
            end
            
            try 
                cla(app.zoom) 
                cla(app.zoom_2) 
            catch 
                ;
            end
            
            app.zoom.XColor = [0.94,0.94,0.94];
            app.TextArea.Value = char('Operation Cancelled');
            set(app.capture_flat,'enable','on');
            set(app.capture_raw,'enable','on');
            set(app.test_leds,'enable','on');
            set(app.set_exposure,'enable','on');
            set(app.focus,'enable','on');
            set(app.cancel,'enable','off');
            app.exposure_gain = 1;
            title(app.zoom, []);
            
            if app.counter_for_restart == app.max_counter
                delete(app.UIFigure); %close the app
                MISHA_capturing; %re-open it again
            else
                app.counter_for_restart = app.counter_for_restart + 1;
            end
                
        end

        % Button pushed function: test_leds
        function test_ledsButtonPushed(app, event)
            
            try
                ser = serialport('COM3',9600);
                write(ser,"0,0\n","string")
                clear ser
            catch 
                write(ser,"0,0\n","string")
                clear ser
            end
            
            app.TextArea.Value{1} = 'Testing LEDs';
            set(app.capture_flat,'enable','off');
            set(app.capture_raw,'enable','off');
            %set(app.test_leds,'enable','off');
            set(app.set_exposure,'enable','off');
            set(app.focus,'enable','off');
            set(app.cancel,'enable','on');
            set(app.TextArea,'visible','on');
            cla(app.UIAxes) 
            
            ser = serialport('COM3',9600);

            %all LED's
                                   
            waves_test = [450,470,490,520, ...
                      560,590,615,630];
                       
            
            for i = 1:size(waves_test,2)
                if app.cancel_capture
                    app.cancel_capture = false;
                    app.TextArea.Value = 'Cancelling... this will take a few seconds';
                    %switch LED off
                    write(ser,"0,0\n","string");
                    pause(3)
                    set(app.capture_flat,'enable','on');
                    set(app.capture_raw,'enable','on');
                    set(app.test_leds,'enable','on');
                    set(app.set_exposure,'enable','on');
                    set(app.focus,'enable','on');
                    set(app.cancel,'enable','off');
                    app.TextArea.Value = 'Operation Cancelled';
                    return
                end
                pause(3);
                try
                    app.TextArea.Value{1+i} = [num2str(waves_test(i)), 'nm'];
                    scroll(app.TextArea, 'bottom')    
                    send_on = [waves_test(i) + ",100"];
                    %switch LED on
                    write(ser,send_on,"string");
                    pause(4);
                    %switch LED off
                    write(ser,"0,0\n","string");
                catch
                    % switch off all LED's
                    write(ser,"0,0\n","string")
                end
                
                
            end
            
            clear ser
            
            app.TextArea.Value = 'Testing completed';
            set(app.capture_flat,'enable','on');
            set(app.capture_raw,'enable','on');
            %set(app.test_leds,'enable','off');
            set(app.set_exposure,'enable','on');
            set(app.focus,'enable','on');
            set(app.cancel,'enable','off');
            
            if app.counter_for_restart == app.max_counter
                delete(app.UIFigure); %close the app
                MISHA_capturing; %re-open it again
            else
                app.counter_for_restart = app.counter_for_restart + 1;
            end

        end

        % Button pushed function: capture_flat
        function capture_flatButtonPushed(app, event)

            try
                ser = serialport('COM3',9600);
                write(ser,"0,0\n","string")
                clear ser
            catch 
                write(ser,"0,0\n","string")
                clear ser
            end
            
            set(app.capture_flat,'enable','off');
            set(app.capture_raw,'enable','off');
            set(app.test_leds,'enable','off');
            set(app.set_exposure,'enable','off');
            set(app.focus,'enable','off');
            set(app.TextArea,'enable','on');
            set(app.cancel,'enable','on');
            set(app.TextArea,'visible','on');
            cla(app.UIAxes) 
            
            % get filepath to save captured images
            user_name= getenv('username');
            filepath = ['C:\Users\',user_name,'\Desktop\'];
            app.capture_info.filepath = uigetdir(filepath);
            % find last folder to asign object name
            pathparts = strsplit(app.capture_info.filepath,filesep);
            app.capture_info.object_name = pathparts{end};
            app.capture_info.fname = 'flat';
            capture_images(app);
            app.TextArea.Value = 'Completed';
            
            set(app.capture_flat,'enable','on');
            set(app.capture_raw,'enable','on');
            set(app.test_leds,'enable','on');
            set(app.set_exposure,'enable','on');
            set(app.focus,'enable','on');
            set(app.cancel,'enable','off');
            app.cancel_capture = false;
            
            if app.counter_for_restart == app.max_counter
                delete(app.UIFigure); %close the app
                MISHA_capturing; %re-open it again
            else
                app.counter_for_restart = app.counter_for_restart + 1;
            end

        end

        % Button pushed function: set_exposure
        function set_exposureButtonPushed(app, event)

            set(app.capture_flat,'enable','off');
            set(app.capture_raw,'enable','off');
            set(app.test_leds,'enable','off');
            set(app.set_exposure,'enable','off');
            set(app.focus,'enable','off');
            set(app.TextArea,'enable','on');
            set(app.cancel,'enable','on');
            set(app.TextArea,'visible','on');
            cla(app.UIAxes) 
            cla(app.zoom) 
            
            try
                ser = serialport('COM3',9600);
                write(ser,"0,0\n","string")
                clear ser
            catch 
                write(ser,"0,0\n","string")
                clear ser
            end
            
            try         
            
                if app.cancel_capture
                    app.cancel_capture = false;
                    app.TextArea.Value = 'Cancelling... this will take a few seconds';
                    % switch off all LED's
                    write(ser,"0,0\n","string")
                    pause(3)
                    set(app.capture_flat,'enable','on');
                    set(app.capture_raw,'enable','on');
                    set(app.test_leds,'enable','on');
                    set(app.set_exposure,'enable','on');
                    set(app.focus,'enable','on');
                    set(app.cancel,'enable','off');
                    
                    app.TextArea.Value = 'Operation Cancelled';
                    return
                end
                ser = serialport('COM3',9600);
                
                app.TextArea.Value = 'Acquiring images at four different light settings...';
    
                led = 9;
                exposure = app.exposure_time_to_set(led) * app.exposure_gain;
        
                pause(3)
                send_on = [app.waves(led) + ",100"];
                % switch on LED
                write(ser,send_on,"string");
                pause(2)
                % capture image
                img = capture_image(app,exposure);
                app.TextArea.Value{2} = 'Top Left: normal (100%)';
                    
                temp = double(img);
                temp = temp/2^12;
                
                if app.cancel_capture
                    app.cancel_capture = false;
                    app.TextArea.Value = 'Cancelling... this will take a few seconds';
                    % switch off all LED's
                    write(ser,"0,0\n","string")
                    pause(3)
                    set(app.capture_flat,'enable','on');
                    set(app.capture_raw,'enable','on');
                    set(app.test_leds,'enable','on');
                    set(app.set_exposure,'enable','on');
                    set(app.focus,'enable','on');
                    set(app.cancel,'enable','off');
                    
                    app.TextArea.Value = 'Operation Cancelled';
                    return
                end
                out_clean = temp * 0 + 1;
                out = [temp,out_clean];
                out2 = [out_clean,out_clean];
                out3 = [out;out2];
                %imshow(out3,'parent',app.UIAxes)
                title(app.UIAxes, []);
                xlabel(app.UIAxes, []);
                ylabel(app.UIAxes, []);
                app.UIAxes.XAxis.TickLabels = {};
                app.UIAxes.YAxis.TickLabels = {};
                % Display image and stretch to fill axes
                I = imshow(out3, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                
                pause(2)
                if app.cancel_capture
                    app.cancel_capture = false;
                    app.TextArea.Value = 'Cancelling... this will take a few seconds';
                    % switch off all LED's
                    write(ser,"0,0\n","string")
                    pause(3)
                    set(app.capture_flat,'enable','on');
                    set(app.capture_raw,'enable','on');
                    set(app.test_leds,'enable','on');
                    set(app.set_exposure,'enable','on');
                    set(app.focus,'enable','on');
                    set(app.cancel,'enable','off');
                    
                    app.TextArea.Value = 'Operation Cancelled';
                    return
                end
                exposure_time_to_set2 = app.exposure_time_to_set(led) * app.exposure_gain2;
                img = capture_image(app,exposure_time_to_set2);   
                app.TextArea.Value{3} = ['Top Right: ',num2str(app.exposure_gain2*100), ' percent'];
                temp2 = double(img);
                temp2 = temp2/2^12;
                
                
                out = [temp,temp2];
                out2 = [out_clean,out_clean];
                out3 = [out;out2];
                %imshow(out3,'parent',app.UIAxes)
                I = imshow(out3, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                
                pause(2)
                if app.cancel_capture
                    app.cancel_capture = false;
                    app.TextArea.Value = 'Cancelling... this will take a few seconds';
                    % switch off all LED's
                    write(ser,"0,0\n","string")
                    pause(3)
                    set(app.capture_flat,'enable','on');
                    set(app.capture_raw,'enable','on');
                    set(app.test_leds,'enable','on');
                    set(app.set_exposure,'enable','on');
                    set(app.focus,'enable','on');
                    set(app.cancel,'enable','off');
                    
                    app.TextArea.Value = 'Operation Cancelled';
                    return
                end
                exposure_time_to_set2 = app.exposure_time_to_set(led) * app.exposure_gain3;
                img = capture_image(app,exposure_time_to_set2);
                app.TextArea.Value{4} = ['Bottom Left: ',num2str(app.exposure_gain3*100), ' percent'];
               
                temp3 = double(img);
                temp3 = temp3/2^12;
                
                out = [temp,temp2];
                out2 = [temp3,out_clean];
                out3 = [out;out2];
                %imshow(out3,'parent',app.UIAxes)
                I = imshow(out3, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                
                pause(2)
                
                exposure_time_to_set2 = app.exposure_time_to_set(led) * app.exposure_gain4;
                img = capture_image(app,exposure_time_to_set2); 
                app.TextArea.Value{5} = ['Bottom Right: ',num2str(app.exposure_gain4*100), ' percent'];
               
                temp4 = double(img);
                temp4 = temp4/2^12;
                
                out = [temp,temp2];
                out2 = [temp3,temp4];
                out3 = [out;out2];
                
                %imshow(out3,'parent',app.UIAxes)
                I = imshow(out3, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
    
                if app.cancel_capture
                    app.cancel_capture = false;
                    app.TextArea.Value = 'Cancelling... this will take a few seconds';
                    % switch off all LED's
                    write(ser,"0,0\n","string")
                    pause(3)
                    set(app.capture_flat,'enable','on');
                    set(app.capture_raw,'enable','on');
                    set(app.test_leds,'enable','on');
                    set(app.set_exposure,'enable','on');
                    set(app.focus,'enable','on');
                    set(app.cancel,'enable','off');
                    
                    app.TextArea.Value = 'Operation Cancelled';
                    return
                end
                % switch off all LED's
                write(ser,"0,0\n","string")
                
                options = {'Left Top','Right Top','Left Bottom','Right Bottom'};
                answer = listdlg('PromptString',{'Select exposure level.',...
                    'Keep exposure settings for left of right / top or bottom image?',''},...
                    'SelectionMode','single','ListString',options, 'ListSize',[160,100]);
                            
                if answer == 1
                    app.exposure_gain = 1;
                elseif answer == 2
                    app.exposure_gain = app.exposure_gain2;
                elseif answer == 3
                    app.exposure_gain = app.exposure_gain3;
                elseif answer == 4
                    app.exposure_gain = app.exposure_gain4; 
                end
                
                app.TextArea.Value = ['Exposure set to ',num2str(app.exposure_gain*100),' percent of standard spectralon tested settings.'];
    
                
                set(app.capture_flat,'enable','on');
                set(app.capture_raw,'enable','on');
                set(app.test_leds,'enable','on');
                set(app.set_exposure,'enable','on');
                set(app.focus,'enable','on'); 
                set(app.cancel,'enable','off');
                app.cancel_capture = false;
            catch
                % switch off all LED's
                write(ser,"0,0\n","string")
                cla(app.UIAxes)
                app.cancel_capture = false;
                set(app.capture_flat,'enable','on');
                set(app.capture_raw,'enable','on');
                set(app.test_leds,'enable','on');
                set(app.set_exposure,'enable','on');
                set(app.focus,'enable','on'); 
                set(app.cancel,'enable','off');
            end
            
            if app.counter_for_restart == app.max_counter
                delete(app.UIFigure); %close the app
                MISHA_capturing; %re-open it again
            else
                app.counter_for_restart = app.counter_for_restart + 1;
            end
        end

        % Callback function
        function capture_darkButtonPushed(app, event)
            
        end

        % Callback function
        function zoomButtonDown(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            ser = serialport('COM3',9600);
            write(ser,"0,0\n","string");
            clear ser
            delete(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {540, 540};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {119, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.focus = uibutton(app.LeftPanel, 'push');
            app.focus.ButtonPushedFcn = createCallbackFcn(app, @focusButtonPushed, true);
            app.focus.Icon = 'focus.png';
            app.focus.Tooltip = {'Manually focus the camera. Sharpness will be displayed in the information box. The higher the value, the sharper the image. Max sharpness is 127 (however unlikely with this camera).'};
            app.focus.Text = '';
            app.capture_raw = uibutton(app.LeftPanel, 'push');
            app.capture_raw.ButtonPushedFcn = createCallbackFcn(app, @capture_rawButtonPushed, true);
            app.capture_raw.Icon = 'capture.png';
            app.capture_raw.Tooltip = {'All 16 wavelengths will be captured and saved. Remember to image in a dark room or close the box.'};
            app.capture_raw.Text = '';
            app.capture_flat = uibutton(app.LeftPanel, 'push');
            app.capture_flat.ButtonPushedFcn = createCallbackFcn(app, @capture_flatButtonPushed, true);
            app.capture_flat.Icon = 'capture_flats.png';
            app.capture_flat.Tooltip = {'Capture a clean uniform white sheet in all wavelengths. Also to be done in a dark room / box.'};
            app.capture_flat.Text = '';
            app.test_leds = uibutton(app.LeftPanel, 'push');
            app.test_leds.ButtonPushedFcn = createCallbackFcn(app, @test_ledsButtonPushed, true);
            app.test_leds.Icon = 'test_leds.PNG';
            app.test_leds.Tooltip = {'This is for display / teaching only. The set of visible LED''s will flash to explain the process. No images will be captured.'};
            app.test_leds.Text = '';
            app.set_exposure = uibutton(app.LeftPanel, 'push');
            app.set_exposure.ButtonPushedFcn = createCallbackFcn(app, @set_exposureButtonPushed, true);
            app.set_exposure.Icon = 'lights.PNG';
            app.set_exposure.Tooltip = {'Set a different light level If the object is really dark. This can help to make the images more legible. '};
            app.set_exposure.Text = '';
            app.cancel = uibutton(app.LeftPanel, 'push');
            app.cancel.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancel.Icon = 'cancel.PNG';
            app.cancel.Tooltip = {'This will cancel any running procedure, even imaging.'};
            app.cancel.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Tooltip = {'Information about the current process will be displayed here.'};
            app.TextArea.Value = {'Information Box'; ''};
            app.zoom_2 = uiaxes(app.RightPanel);
            app.zoom_2.XTick = [];
            app.zoom_2.YTick = [];
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.zoom = uiaxes(app.RightPanel);
            app.zoom.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.zoom.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.zoom.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MISHA_capturing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end