classdef test_app_pixelink < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Capture   matlab.ui.control.Button
        TextArea  matlab.ui.control.TextArea
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                imaqregister('C:\Program Files\test_camera\application\pixelinkimaq.dll')
            catch
                ;
            end
            try
                imaqregister('pixelinkimaq.dll')
            catch
                ;
            end
            try
                imaqregister
            catch
                ;
            end
            info = imaqhwinfo;
            app.TextArea.Value = char(info.InstalledAdaptors);
        end

        % Button pushed function: Capture
        function CaptureButtonPushed(app, event)
            % connecting to default camera
            vid=videoinput('pixelinkimaq', 1, 'MONO16_5472x3648')
            vid.FramesPerTrigger = 1;
            src = getselectedsource(vid);
            set(src,'ExposureMode','auto');
            
            frame=getsnapshot(vid);
            img = (double(frame)-min(double(frame(:))))/(double(max(frame(:)))-min(double(frame(:))));
            
            imshow(img, 'Parent', app.UIAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Capture = uibutton(app.UIFigure, 'push');
            app.Capture.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_app_pixelink

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end